classdef Efectos_de_Audio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MUSICTab                       matlab.ui.container.Tab
        UploadFileLabel                matlab.ui.control.Label
        SelectsongButton               matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        PlayButton_2                   matlab.ui.control.Button
        PlayOriginalAudioLabel         matlab.ui.control.Label
        FILTERTab                      matlab.ui.container.Tab
        FILTERButton                   matlab.ui.control.Button
        ApplyFilterLabel               matlab.ui.control.Label
        SelectFCL1EditFieldLabel       matlab.ui.control.Label
        SelectFCL1EditField            matlab.ui.control.EditField
        SelectFCH1Label                matlab.ui.control.Label
        SelectFCH1EditField            matlab.ui.control.EditField
        SelectFCL2EditFieldLabel       matlab.ui.control.Label
        SelectFCL2EditField            matlab.ui.control.EditField
        SelectFCH2EditField_2Label     matlab.ui.control.Label
        SelectFCH2EditField            matlab.ui.control.EditField
        GRAPHButton                    matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        EFFECT1Tab                     matlab.ui.container.Tab
        FIRSTBANDLabel                 matlab.ui.control.Label
        DISTORTIONAMPLITUDKnobLabel    matlab.ui.control.Label
        DISTORTIONAMPLITUDKnob         matlab.ui.control.Knob
        AMOUNTOFDELAYKnobLabel         matlab.ui.control.Label
        AMOUNTOFDELAYKnob              matlab.ui.control.Knob
        VARIATIONKnobLabel             matlab.ui.control.Label
        VARIATIONKnob                  matlab.ui.control.Knob
        MODULATIONFREQKnobLabel        matlab.ui.control.Label
        MODULATIONFREQKnob             matlab.ui.control.Knob
        ECOAMPLITUDLabel               matlab.ui.control.Label
        ECOAMPLITUDKnob                matlab.ui.control.Knob
        RHYTHMLabel                    matlab.ui.control.Label
        RHYTHMKnob                     matlab.ui.control.Knob
        WIDTHKnobLabel                 matlab.ui.control.Label
        WIDTHKnob                      matlab.ui.control.Knob
        ECOSwitch_3Label               matlab.ui.control.Label
        ECOSwitch                      matlab.ui.control.ToggleSwitch
        DISTORTIONSwitch_3Label        matlab.ui.control.Label
        DISTORTIONSwitch               matlab.ui.control.ToggleSwitch
        REVERBSwitch_3Label            matlab.ui.control.Label
        REVERBSwitch                   matlab.ui.control.ToggleSwitch
        VIBRATOSwitch_3Label           matlab.ui.control.Label
        VIBRATOSwitch                  matlab.ui.control.ToggleSwitch
        FLANGERSwitchLabel             matlab.ui.control.Label
        FLANGERSwitch                  matlab.ui.control.ToggleSwitch
        EFFECT2Tab                     matlab.ui.container.Tab
        SECONDBANDLabel                matlab.ui.control.Label
        ECOSwitch_2Label               matlab.ui.control.Label
        ECOSwitch_2                    matlab.ui.control.ToggleSwitch
        DISTORTIONSwitch_2Label        matlab.ui.control.Label
        DISTORTIONSwitch_2             matlab.ui.control.ToggleSwitch
        REVERBSwitch_2Label            matlab.ui.control.Label
        REVERBSwitch_2                 matlab.ui.control.ToggleSwitch
        VIBRATOSwitch_2Label           matlab.ui.control.Label
        VIBRATOSwitch_2                matlab.ui.control.ToggleSwitch
        FLANGERSwitch_2Label           matlab.ui.control.Label
        FLANGERSwitch_2                matlab.ui.control.ToggleSwitch
        DISTORTIONAMPLITUDKnob_2Label  matlab.ui.control.Label
        DISTORTIONAMPLITUDKnob_2       matlab.ui.control.Knob
        AMOUNTOFDELAYKnob_2Label       matlab.ui.control.Label
        AMOUNTOFDELAYKnob_2            matlab.ui.control.Knob
        MODULATIONFREQKnob_2Label      matlab.ui.control.Label
        MODULATIONFREQKnob_2           matlab.ui.control.Knob
        ECOAMPLITUDKnob_2Label         matlab.ui.control.Label
        ECOAMPLITUDKnob_2              matlab.ui.control.Knob
        VARIATIONKnob_2Label           matlab.ui.control.Label
        VARIATIONKnob_2                matlab.ui.control.Knob
        RHYTHMKnob_2Label              matlab.ui.control.Label
        RHYTHMKnob_2                   matlab.ui.control.Knob
        WIDTHKnob_2Label               matlab.ui.control.Label
        WIDTHKnob_2                    matlab.ui.control.Knob
        REPRODUCTIONTab                matlab.ui.container.Tab
        PlayMusicLabel                 matlab.ui.control.Label
        SaveDataLabel                  matlab.ui.control.Label
        SaveAudioLabel                 matlab.ui.control.Label
        AudioNameEditFieldLabel        matlab.ui.control.Label
        AudioNameEditField             matlab.ui.control.EditField
        DataNameEditFieldLabel         matlab.ui.control.Label
        DataNameEditField              matlab.ui.control.EditField
        VOLUME1KnobLabel               matlab.ui.control.Label
        VOLUME1Knob                    matlab.ui.control.Knob
        VOLUME2KnobLabel               matlab.ui.control.Label
        VOLUME2Knob                    matlab.ui.control.Knob
        SaveAudioButton                matlab.ui.control.Button
        SaveDataButton                 matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectsongButton
        function SelectsongButtonPushed(app, event)
            [file,path]=uigetfile('.wav');
            if file==0
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                [x, Fs] = audioread(file);
                assignin("base","x",x);
                assignin("base","Fs",Fs);
                app.EditField.Value=file;
                [row,col] = size(x);
                if (col == 1)
                    app.TextArea.Value = 'Mono audio';
                elseif (col == 2)
                    app.TextArea.Value = 'Sterio audio';
                end
            end
        end

        % Button pushed function: FILTERButton
        function FILTERButtonPushed(app, event)
            x=evalin("base","x");
            Fs=evalin("base","Fs");
            [row,col] = size(x);
            if (col == 1)
                app.TextArea.Value = 'Mono audio';
            elseif (col == 2)
                app.TextArea.Value = 'Sterio audio';
            end
            % Definición del Periodo de Muestreo
            Ts = 1/Fs;
            
            %% Diseño de Filtro  1 por Método Indirecto
            syms s z
            % Prototipo analógico
            H_1(s) = 1 / (s^2+sqrt(2)*s+1);
            % Frecuencia y período de muestreo
            Fl_1 = double(convertCharsToStrings(app.SelectFCL1EditField.Value))
            Fh_1 = double(convertCharsToStrings(app.SelectFCH1EditField.Value))
            % Frecuencia y período de muestreo
            Fl_2 = double(convertCharsToStrings(app.SelectFCL2EditField.Value))
            Fh_2 = double(convertCharsToStrings(app.SelectFCH2EditField.Value))
            if (Fl_1 > Fh_1) && (Fl_2 > Fh_2)
                app.TextArea.Value  = 'Error in first and second filter. First cut frequency bigger than second';
            end
            if (Fl_1 > Fh_1) && (Fl_2 < Fh_2)
                app.TextArea.Value  = 'Error in first filter. First cut frequency bigger than second';
            elseif (Fl_1 < Fh_1) && (Fl_2 < Fh_2)
                % Frecuencias de corte digitales requeridas
                wl_1 = (2*pi*Fl_1)*Ts;
                wh_1 = (2*pi*Fh_1)*Ts;
                % Encontramos las frecuencias analógicas deseadas Wl y Wu mediante 
                % frequency pre-warping
                Wl_1 = (2/Ts)*tan((wl_1)/2);
                Wh_1 = (2/Ts)*tan((wh_1)/2);
                % Generamos el filtro deseado a partir del prototipo
                Hbpf_a_1(s) = H_1(1*((s^2+Wl_1*Wh_1)/((Wh_1-Wl_1)*s)));
                % Generamos el filtro digital mediante la transformación bilineal
                Hbpf_1(z) = Hbpf_a_1((2/Ts)*((z-1)/(z+1)));
                % Obtenemos el numerador y denominador
                [N_1, D_1] = numden(simplifyFraction(Hbpf_1));
                % Obtenemos los coeficientes del numerador y denominador
                b_1 = fliplr(double(coeffs(N_1)));
                a_1 = fliplr(double(coeffs(D_1)));
                % Normalizamos
                b_1 = b_1 / a_1(1);
                a_1 = a_1 / a_1(1);
                % Se observa la función de transferencia
                Hf_1 = tf(b_1, a_1, Ts);
                assignin("base","b_1",b_1);
                assignin("base","a_1",a_1);
               
                y_1 = filter(b_1, a_1, x);
                assignin("base","y_1",y_1);
                assignin("base","ch1_x",y_1);
                assignin("base","ch2_x",y_1);
                assignin("base","ch3_x",y_1);
                assignin("base","ch4_x",y_1);
                assignin("base","ch5_x",y_1);
                assignin("base","ch6_x",y_1);
            end
            
            %% Diseño de Filtro 2 por Método Indirecto
            syms s z
            % Prototipo analógico
            H_2(s) = 1 / (s^2+sqrt(2)*s+1);            
            if (Fl_2 > Fh_2) && (Fl_1 < Fh_1)
                app.TextArea.Value = 'Error in second filter. First cut frequency bigger than second';
            elseif (Fl_2 < Fh_2) && (Fl_1 < Fh_1)
                % Frecuencias de corte digitales requeridas
                wl_2 = (2*pi*Fl_2)*Ts;
                wh_2 = (2*pi*Fh_2)*Ts;
                % Encontramos las frecuencias analógicas deseadas Wl y Wu mediante 
                % frequency pre-warping
                Wl_2 = (2/Ts)*tan((wl_2)/2);
                Wh_2 = (2/Ts)*tan((wh_2)/2);
                % Generamos el filtro deseado a partir del prototipo
                Hbpf_a_2(s) = H_2(1*((s^2+Wl_2*Wh_2)/((Wh_2-Wl_2)*s)));
                % Generamos el filtro digital mediante la transformación bilineal
                Hbpf_2(z) = Hbpf_a_2((2/Ts)*((z-1)/(z+1)));
                % Obtenemos el numerador y denominador
                [N_2, D_2] = numden(simplifyFraction(Hbpf_2));
                % Obtenemos los coeficientes del numerador y denominador
                b_2 = fliplr(double(coeffs(N_2)));
                a_2 = fliplr(double(coeffs(D_2)));
                % Normalizamos
                b_2 = b_2 / a_2(1);
                a_2 = a_2 / a_2(1);
                % Se observa la función de transferencia
                Hf_2 = tf(b_2, a_2, Ts);
                assignin("base","b_2",b_2);
                assignin("base","a_2",a_2);
                
                y_2 = filter(b_2, a_2, x);
                assignin("base","y_2",y_2);
                assignin("base","ch7_x",y_2);
                assignin("base","ch8_x",y_2);
                assignin("base","ch9_x",y_2);
                assignin("base","ch10_x",y_2);
                assignin("base","ch11_x",y_2);
                assignin("base","ch12_x",y_2);
            end
        end

        % Callback function
        function Button_2ValueChanged(app, event)
            boton = app.Button_2.Value;
            x=evalin("base","ch1_x");
            Fs=evalin("base","Fs");
            ch1_x=evalin("base","ch1_x");
            
          
%% ----------------------------------------------------------------------------------------ECO1------------------                
                assignin("base","ECO1",app.ECOSwitch.Value);
                value = evalin("base","ECO1");
                assignin("base","DISTURTION1",app.DISTORTIONSwitch.Value);
                value2 = evalin("base","DISTURTION1");
                assignin("base","REVERB1",app.REVERBSwitch.Value);
                value3 = evalin("base","REVERB1");
                assignin("base","VIBRATO1",app.VIBRATOSwitch.Value);
                value4 = evalin("base","VIBRATO1");
                assignin("base","FLANGER1",app.FLANGERSwitch.Value);
                value5 = evalin("base","FLANGER1");
                assignin("base","alpha_ECO1",app.ECOAMPLITUDKnob.Value);
                alpha=evalin("base","alpha_ECO1");
                D=.25*Fs;
                y2 = zeros(size(ch1_x));
                xD2=0;
                yD2=0;
                N = length(ch1_x);
                if strcmp(value,'On')
                    for n= 1:N
                        if n<D+1
                            y2(n)=ch1_x(n);
                        end
                        if n>=D+1
                            y2(n)=ch1_x(n)+(1-alpha)*ch1_x(n-D)+alpha*y2(n-D);
                        end
                    end
                    assignin("base","ch2_x",y2);
                    assignin("base","ch3_x",y2);
                else
                    assignin("base","ch2_x",ch1_x);
                    assignin("base","ch3_x",ch1_x);
                end
%----------------------------DISTORTION1------------------------------------------------------------
                ch2_x=evalin("base","ch2_x");
                ch3_x = evalin("base","ch3_x");
                gain=1
                assignin("base","mix_DISTURTION1",app.DISTORTIONAMPLITUDKnob.Value);
                mix=evalin("base","mix_DISTURTION1");
                if strcmp(value2,'On')
                    q=ch2_x*gain;
                    z=sign(q).*(1-exp(-abs(q)));
                    y=mix*z+(1-mix)*ch2_x;
                    assignin("base","ch3_x",y);
                    assignin("base","ch4_x",y);
                else
                   assignin("base","ch3_x",ch2_x);
                   assignin("base","ch4_x",ch2_x);
                end
%---------------------------------------------------REVERBERATION1----------------------------------------------------
            ch3_x = evalin("base","ch3_x");
            x1 = ch3_x(:,1);
            x2 = ch3_x(:,2);
            
            %-------------------------------------------------------------------------
            %*********************** GENERACIÓN DE IMPULSO ***************************
            %-------------------------------------------------------------------------
            % Definición de tamaños de memorias y efectos
            xh = zeros(1, 2500);
            xh(1) = 1;
            A = zeros(1, 44000);
            Adelay = 1000;
            ir = zeros(1, 2500);
            assignin("base","g_REVERB1",app.AMOUNTOFDELAYKnob.Value);
            g = evalin("base","g_REVERB1");
            if strcmp(value3,'On')
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n = 1:length(ir)
                    tmp = A(Adelay) + xh(n)*(-g);
                    A = [(tmp*g + xh(n))' A(1:length(A) - 1)];
                    ir(n) = tmp;
                end
                %-------------------------------------------------------------------------
                % Convolución entre los canales y el impulso creado
                ch1_y2 = conv (x1,ir);
                ch2_y2 = conv (x2,ir);
                
                % Concatenación de los dos canales
                y3 = horzcat(ch1_y2, ch2_y2);

                assignin("base","ch4_x",y3);
                assignin("base","ch5_x",y3);
            else
                assignin("base","ch4_x",ch3_x);
                assignin("base","ch5_x",ch3_x);
            end
%-------------------------------VIBRATO1------------------------------------------

            ch4_x=evalin("base","ch4_x");
            x3 = ch4_x(:,1);
            x4 = ch4_x(:,2);
            
            % Definición de frecuencia de modulación
            Modfreq = app.MODULATIONFREQKnob.Value 
            assignin("base","Modfreq_VIBRATO1",Modfreq);
            % Definición de delay base
            
            Width = app.WIDTHKnob.Value;
            assignin("base","Width_VIBRATO1",Width);
            % Delay base de la entrada
            Delay=Width; 
            % Delay base por número de muestras
            DELAY=round(Delay*Fs); 
            % Ancho de modulación por número de muestras
            WIDTH=round(Width*Fs); 
            % Frecuencia de modulación por número de muestras
            MODFREQ=Modfreq/Fs; 
            if strcmp(value4,'On')
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_1=length(x3);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch1_y=zeros(size(x3));     
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_1-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x3(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch1_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_2=length(x4);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch2_y=zeros(size(x4));    
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_2-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x4(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch2_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                % Concatenación de los dos canales
                y4 = horzcat(ch1_y, ch2_y);
                assignin("base","ch5_x",y4);
                assignin("base","ch6_x",y4);
            else
                assignin("base","ch5_x",ch4_x);
                assignin("base","ch6_x",ch4_x);
            end
%--------------------------------------------------FLANGER-----------------------------------------------------
            ch5_x=evalin("base","ch5_x");
            assignin("base","r_FLANGER1",app.VARIATIONKnob.Value);
            r = evalin("base","r_FLANGER1");
            assignin("base","v_FLANGER1",app.RHYTHMKnob.Value);
            v = evalin("base","v_FLANGER1");
            if strcmp(value5,'On')
                x5 = ch5_x(:,1);
                x6 = ch5_x(:,2);
                
                % Aplicación de la función flange, guardandola en una nueva varaible
                ch1_y3 = flange(Fs,v,x5,r);
                ch2_y3 = flange(Fs,v,x6,r);
                
                % Concatenación de los dos canales
                y5 = horzcat(ch1_y3, ch2_y3);
                assignin("base","ch6_x",y5);
            else
                assignin("base","ch6_x",ch5_x);
            end
            
%% ----------------------------------------------------------------------------------------ECO2------------------                
                ch7_x=evalin("base","ch7_x");                
                assignin("base","ECO2",app.ECOSwitch_2.Value);
                value6 = evalin("base","ECO2");
                assignin("base","DISTURTION2",app.DISTORTIONSwitch_2.Value);
                value7 = evalin("base","DISTURTION2");
                assignin("base","REVERB2",app.REVERBSwitch_2.Value);
                value8 = evalin("base","REVERB2");
                assignin("base","VIBRATO2",app.VIBRATOSwitch_2.Value);
                value9 = evalin("base","VIBRATO2");
                assignin("base","FLANGER2",app.FLANGERSwitch_2.Value);
                value10 = evalin("base","FLANGER2");
                assignin("base","alpha_ECO2",app.ECOAMPLITUDKnob_2.Value);
                alpha1=evalin("base","alpha_ECO2");
                D1=.25*Fs;
                y6 = zeros(size(ch7_x));
                xD2=0;
                yD2=0;
                N = length(ch7_x);
                if strcmp(value6,'On')
                    for n= 1:N
                        if n<D1+1
                            y6(n)=ch7_x(n);
                        end
                        if n>=D1+1
                            y6(n)=ch7_x(n)+(1-alpha1)*ch7_x(n-D1)+alpha1*y6(n-D1);
                        end
                    end
                    assignin("base","ch8_x",y6);
                    assignin("base","ch9_x",y6);
                else
                    assignin("base","ch8_x",ch7_x);
                    assignin("base","ch9_x",ch7_x);
                end
%----------------------------DISTORTION2------------------------------------------------------------
                ch8_x=evalin("base","ch8_x");
                gain=1;
                assignin("base","mix_DISTURTION2",app.DISTORTIONAMPLITUDKnob_2.Value);
                mix2=evalin("base","mix_DISTURTION2");
                if strcmp(value7,'On')
                    q=ch7_x*gain;
                    z=sign(q).*(1-exp(-abs(q)));
                    y=mix2*z+(1-mix2)*ch7_x;
                    assignin("base","ch9_x",y);
                    assignin("base","ch10_x",y);
                else
                   assignin("base","ch9_x",ch8_x);
                   assignin("base","ch10_x",ch8_x);
                end
%---------------------------------------------------REVERBERATION2----------------------------------------------------
            ch9_x = evalin("base","ch9_x");
            x1 = ch9_x(:,1);
            x2 = ch9_x(:,2);
            
            %-------------------------------------------------------------------------
            %*********************** GENERACIÓN DE IMPULSO ***************************
            %-------------------------------------------------------------------------
            % Definición de tamaños de memorias y efectos
            xh = zeros(1, 2500);
            xh(1) = 1;
            A = zeros(1, 44000);
            Adelay = 1000;
            ir = zeros(1, 2500);
            assignin("base","g_REVERB2",app.AMOUNTOFDELAYKnob_2.Value);
            g2 = evalin("base","g_REVERB2");
            if strcmp(value8,'On')
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n = 1:length(ir)
                    tmp = A(Adelay) + xh(n)*(-g2);
                    A = [(tmp*g2 + xh(n))' A(1:length(A) - 1)];
                    ir(n) = tmp;
                end
                %-------------------------------------------------------------------------
                % Convolución entre los canales y el impulso creado
                ch1_y2 = conv (x1,ir);
                ch2_y2 = conv (x2,ir);
                
                % Concatenación de los dos canales
                y3 = horzcat(ch1_y2, ch2_y2);

                assignin("base","ch10_x",y3);
                assignin("base","ch11_x",y3);
            else
                assignin("base","ch10_x",ch9_x);
                assignin("base","ch11_x",ch9_x);
            end
%-------------------------------VIBRATO2------------------------------------------

            ch10_x=evalin("base","ch10_x");
            x3 = ch10_x(:,1);
            x4 = ch10_x(:,2);
            
            % Definición de frecuencia de modulación 
            assignin("base","Modfreq_VIBRATO2",app.MODULATIONFREQKnob_2.Value)
            Modfreq2 = evalin("base","Modfreq_VIBRATO2"); 
            % Definición de delay base
            assignin("base","Width_VIBRATO2",app.WIDTHKnob_2.Value);
            Width2 = evalin("base","Width_VIBRATO2"); 
            % Delay base de la entrada
            Delay=Width2; 
            % Delay base por número de muestras
            DELAY=round(Delay*Fs); 
            % Ancho de modulación por número de muestras
            WIDTH=round(Width2*Fs); 
            % Frecuencia de modulación por número de muestras
            MODFREQ=Modfreq2/Fs; 
            if strcmp(value9,'On')
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_1=length(x3);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch1_y=zeros(size(x3));     
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_1-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x3(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch1_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_2=length(x4);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch2_y=zeros(size(x4));    
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_2-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x4(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch2_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                % Concatenación de los dos canales
                y4 = horzcat(ch1_y, ch2_y);
                assignin("base","ch11_x",y4);
                assignin("base","ch12_x",y4);
            else
                assignin("base","ch11_x",ch10_x);
                assignin("base","ch12_x",ch10_x);
            end
%--------------------------------------------------FLANGER2-----------------------------------------------------
            ch11_x=evalin("base","ch11_x");            
            assignin("base","r_FLANGER2",app.VARIATIONKnob_2.Value);
            r2 = evalin("base","r_FLANGER2");
            assignin("base","v_FLANGER2",app.RHYTHMKnob_2.Value);
            v2 = evalin("base","v_FLANGER2");
            if strcmp(value10,'On')
                x5 = ch11_x(:,1);
                x6 = ch11_x(:,2);
                
                % Aplicación de la función flange, guardandola en una nueva varaible
                ch1_y3 = flange(Fs,v2,x5,r2);
                ch2_y3 = flange(Fs,v2,x6,r2);
                
                % Concatenación de los dos canales
                y5 = horzcat(ch1_y3, ch2_y3);
                assignin("base","ch12_x",y5);
            else
                assignin("base","ch12_x",ch11_x);
            end
%%---------------------------------AMPLITUD Y SUMA---------------------------------------------------------            
                ch2_x=evalin("base","ch2_x");
                ch3_x=evalin("base","ch3_x");
                ch4_x=evalin("base","ch4_x");
                ch5_x=evalin("base","ch5_x");
                ch6_x=evalin("base","ch6_x");
                ch8_x=evalin("base","ch8_x");
                ch9_x=evalin("base","ch9_x");
                ch10_x=evalin("base","ch10_x");
                ch11_x=evalin("base","ch11_x");
                ch12_x=evalin("base","ch12_x");
                assignin("base","VOLUME1",app.VOLUME1Knob.Value);
                V1=evalin("base","VOLUME1");
                assignin("base","VOLUME2",app.VOLUME2Knob.Value);
                V2=evalin("base","VOLUME2");
                if length(ch6_x) == length(ch12_x)
                    y=V1.*ch6_x+V2.*ch12_x;
                end
                if length(ch6_x) > length(ch12_x)
                    L=length(ch6_x)-length(ch12_x);
                    ch12_x=[ch12_x;zeros(L,2)];
                    y=V1.*ch6_x+V2.*ch12_x;
                end
                if length(ch12_x) > length(ch6_x)
                    L=length(ch12_x)-length(ch6_x);
                    ch6_x=[ch6_x;zeros(L,2)];
                    y=V1.*ch6_x+V2.*ch12_x;
                end
                assignin("base","y",y);
                soundsc(y,Fs);               
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            y=evalin("base","y");
            Fs=evalin("base","Fs");
            file=convertCharsToStrings(app.AudioNameEditField.Value);
            name=strcat(file,'.wav');
            audiowrite(name,y,Fs);            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            file=convertCharsToStrings(app.DataNameEditField.Value);
            name=strcat(file,'.mat');
            Fs=evalin("base","Fs");
            x=evalin("base","x");
            ch1_x=evalin("base","ch1_x");
            ch2_x=evalin("base","ch2_x");
            ch3_x=evalin("base","ch3_x");
            ch4_x=evalin("base","ch4_x");
            ch5_x=evalin("base","ch5_x");
            ch6_x=evalin("base","ch6_x");
            ch7_x=evalin("base","ch7_x");
            ch8_x=evalin("base","ch8_x");
            ch9_x=evalin("base","ch9_x");
            ch10_x=evalin("base","ch10_x");
            ch11_x=evalin("base","ch11_x");
            ch12_x=evalin("base","ch12_x");
            y_1=evalin("base","y_1");
            y_2=evalin("base","y_2");
            ECO1 = evalin("base","ECO1");               
            DISTURTION1 = evalin("base","DISTURTION1");                
            REVERB1 = evalin("base","REVERB1");                
            VIBRATO1 = evalin("base","VIBRATO1");
            FLANGER1 = evalin("base","FLANGER1");
            ECO2 = evalin("base","ECO2");               
            DISTURTION2 = evalin("base","DISTURTION2");                
            REVERB2 = evalin("base","REVERB2");                
            VIBRATO2 = evalin("base","VIBRATO2");
            FLANGER2 = evalin("base","FLANGER2");
            alpha_ECO1=evalin("base","alpha_ECO1");
            alpha_ECO2=evalin("base","alpha_ECO2");
            mix_DISTURTION1=evalin("base","mix_DISTURTION1");
            mix_DISTURTION2=evalin("base","mix_DISTURTION2");
            g_REVERB1 = evalin("base","g_REVERB1");
            g_REVERB2 = evalin("base","g_REVERB2");
            Modfreq_VIBRATO1 = evalin("base","Modfreq_VIBRATO1"); 
            Modfreq_VIBRATO2 = evalin("base","Modfreq_VIBRATO2"); 
            Width_VIBRATO1 = evalin("base","Width_VIBRATO1"); 
            Width_VIBRATO2 = evalin("base","Width_VIBRATO2"); 
            r_FLANGER1 = evalin("base","r_FLANGER1");
            v_FLANGER1 = evalin("base","v_FLANGER1");
            r_FLANGER2 = evalin("base","r_FLANGER2");
            v_FLANGER2 = evalin("base","v_FLANGER2");
            y= evalin("base","y");
            y_1=evalin("base","y_1");
            y_2=evalin("base","y_2");
            a_1=evalin("base","a_1");
            b_1=evalin("base","b_2");
            a_2=evalin("base","a_2");
            b_2=evalin("base","b_2");
            uisave({'Fs','x','ch1_x','ch2_x','ch3_x','ch4_x','ch5_x','ch6_x','ch7_x','ch8_x',...
                    'ch9_x','ch10_x','ch11_x','ch12_x','y_1','y_2','ECO1','ECO2','DISTURTION1',...
                    'DISTURTION2','REVERB1','REVERB2','VIBRATO1','VIBRATO2','FLANGER1','FLANGER2',...
                    'alpha_ECO1','alpha_ECO2','mix_DISTURTION1','mix_DISTURTION2','g_REVERB1',...
                    'g_REVERB2','Modfreq_VIBRATO1','Modfreq_VIBRATO2','Width_VIBRATO1',...
                    'Width_VIBRATO2','r_FLANGER1','r_FLANGER2','v_FLANGER1','v_FLANGER2','y',...
                    'y_1','y_2','a_1','b_1','a_2','b_2'},name);
        end

        % Button pushed function: GRAPHButton
        function GRAPHButtonPushed(app, event)
            %% Graficas de filtrado para Segunda Señal
            %Canal 1 de x
            x=evalin("base","x");
            y_1=evalin("base","y_1");
            Fs=evalin("base","Fs");
            b_1=evalin("base","b_1");
            a_1=evalin("base","a_1");
            [row,col] = size(x);
            if (col == 1)
                ch1_y_1 = y_1(:,1);
                ch2_y_1 = 0;
                ch1_x = x(:,1);
                ch2_x = 0;
            elseif (col == 2)
                ch1_y_1 = y_1(:,1);
                ch2_y_1 = y_1(:,2);
                ch1_x = x(:,1);
                ch2_x = x(:,2);                
            end
            % Se observa el filtro diseñado
            fvtool(b_1,a_1,'Fs',Fs);
            %% Graficas de filtrado para Primera Señal
            %Canal 1 de x
            yX1_x1 = fft(ch1_x);
            yX1_bi_x1 = abs(yX1_x1/length(ch1_x));
            yX1_uni_x1 = yX1_bi_x1(1:length(ch1_x)/2+1);
            yX1_uni_x1(2:end-1) = 2*yX1_uni_x1(2:end-1);
            axis_x_2 = Fs*(0:(length(ch1_x)/2))/length(ch1_x);
            %Canal 2 de x
            yX1_x2 = fft(ch2_x);
            yX1_bi_x2 = abs(yX1_x2/length(ch2_x));
            yX1_uni_x2 = yX1_bi_x2(1:length(ch2_x)/2+1);
            yX1_uni_x2(2:end-1) = 2*yX1_uni_x2(2:end-1);
            %Canal 1 de y_1
            yX1_y1_1 = fft(ch1_y_1);
            yX1_bi_y1_1 = abs(yX1_y1_1/length(ch1_y_1));
            yX1_uni_y1_1 = yX1_bi_y1_1(1:length(ch1_y_1)/2+1);
            yX1_uni_y1_1(2:end-1) = 2*yX1_uni_y1_1(2:end-1);
            %Canal 2 de y_1
            yX1_y2_1 = fft(ch2_y_1);
            yX1_bi_y2_1 = abs(yX1_y2_1/length(ch2_y_1));
            yX1_uni_y2_1 = yX1_bi_y2_1(1:length(ch2_y_1)/2+1);
            yX1_uni_y2_1(2:end-1) = 2*yX1_uni_y2_1(2:end-1);
            %Gráfica
            if col == 1
                figure(2);
                clf;
                subplot(2, 1, 1);
                stem(axis_x_2, yX1_uni_x1);
                axis([0 2205 0 0.2])
                %plot(axis_x_2(1:22050), yX1_uni_x1(1:22050));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de x.');
                subplot(2, 1, 2);
                stem(axis_x_2, yX1_uni_y1_1);
                axis([0 2205 0 0.05])
                %plot(axis_x_2(1:22050), yX1_uni_y1_1(1:22050));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de y_1.');
            elseif col == 2
                figure(2);
                clf;
                subplot(2, 2, 1);
                plot(axis_x_2(1:44100), yX1_uni_x1(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de x.');
                subplot(2, 2, 2);
                plot(axis_x_2(1:44100), yX1_uni_x2(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 2 de x.');
                subplot(2, 2, 3);
                plot(axis_x_2(1:44100), yX1_uni_y1_1(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de y_1.');
                subplot(2, 2, 4);
                plot(axis_x_2(1:44100), yX1_uni_y2_1(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 2 de y_1.');
            end
            
            y_2=evalin("base","y_2");
            b_2=evalin("base","b_2");
            a_2=evalin("base","a_2");
            % Se observa el filtro diseñado
            fvtool(b_2,a_2,'Fs',Fs);
            if (col == 1)
                ch1_y_2 = y_2(:,1);
                ch2_y_2 = 0;
            elseif (col == 2)
                ch1_y_2 = y_2(:,1);
                ch2_y_2 = y_2(:,2);
            end
            %% Graficas de filtrado para Segunda Señal
            %Canal 1 de x
            yX1_x1 = fft(ch1_x);
            yX1_bi_x1 = abs(yX1_x1/length(ch1_x));
            yX1_uni_x1 = yX1_bi_x1(1:length(ch1_x)/2+1);
            yX1_uni_x1(2:end-1) = 2*yX1_uni_x1(2:end-1);
            axis_x_2 = Fs*(0:(length(ch1_x)/2))/length(ch1_x);
            %Canal 2 de x
            yX1_x2 = fft(ch2_x);
            yX1_bi_x2 = abs(yX1_x2/length(ch2_x));
            yX1_uni_x2 = yX1_bi_x2(1:length(ch2_x)/2+1);
            yX1_uni_x2(2:end-1) = 2*yX1_uni_x2(2:end-1);
            %Canal 1 de y_2
            yX1_y1_2 = fft(ch1_y_2);
            yX1_bi_y1_2 = abs(yX1_y1_2/length(ch1_y_2));
            yX1_uni_y1_2 = yX1_bi_y1_2(1:length(ch1_y_2)/2+1);
            yX1_uni_y1_2(2:end-1) = 2*yX1_uni_y1_2(2:end-1);
            %Canal 2 de y_2
            yX1_y2_2 = fft(ch2_y_2);
            yX1_bi_y2_2 = abs(yX1_y2_2/length(ch2_y_2));
            yX1_uni_y2_2 = yX1_bi_y2_2(1:length(ch2_y_2)/2+1);
            yX1_uni_y2_2(2:end-1) = 2*yX1_uni_y2_2(2:end-1);
            
           %% Graficas de filtrado para Segunda Señal
            %Canal 1 de x
            if col == 1
                %Gráfica
                figure(4);
                clf;
                subplot(2, 1, 1);
                stem(axis_x_2, yX1_uni_x1);
                axis([0 2205 0 0.2])
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de x.');
                subplot(2, 1, 2);
                stem(axis_x_2, yX1_uni_y1_2);
                axis([0 2205 0 0.05])
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de y_2.');
            elseif col == 2
                %Gráfica
                figure(4);
                clf;
                subplot(2, 2, 1);
                plot(axis_x_2(1:44100), yX1_uni_x1(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de x.');
                subplot(2, 2, 2);
                plot(axis_x_2(1:44100), yX1_uni_x2(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 2 de x.');
                subplot(2, 2, 3);
                plot(axis_x_2(1:44100), yX1_uni_y1_2(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 1 de y_2.');
                subplot(2, 2, 4);
                plot(axis_x_2(1:44100), yX1_uni_y2_2(1:44100));
                grid on;
                xlabel('Frecuencia (Hz)');
                ylabel('Amplitud');
                title('Espectro unilateral del canal 2 de y_2.');
            end
        end

        % Button pushed function: PlayButton_2
        function StartStopButtonPushed(app, event)
            x=evalin("base","x");
            Fs=evalin("base","Fs");
            soundsc(x,Fs); 
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            x=evalin("base","ch1_x");
            Fs=evalin("base","Fs");
            ch1_x=evalin("base","ch1_x");
            
          
%% ----------------------------------------------------------------------------------------ECO1------------------                
                assignin("base","ECO1",app.ECOSwitch.Value);
                value = evalin("base","ECO1");
                assignin("base","DISTURTION1",app.DISTORTIONSwitch.Value);
                value2 = evalin("base","DISTURTION1");
                assignin("base","REVERB1",app.REVERBSwitch.Value);
                value3 = evalin("base","REVERB1");
                assignin("base","VIBRATO1",app.VIBRATOSwitch.Value);
                value4 = evalin("base","VIBRATO1");
                assignin("base","FLANGER1",app.FLANGERSwitch.Value);
                value5 = evalin("base","FLANGER1");
                assignin("base","alpha_ECO1",app.ECOAMPLITUDKnob.Value);
                alpha=evalin("base","alpha_ECO1");
                D=.25*Fs;
                y2 = zeros(size(ch1_x));
                xD2=0;
                yD2=0;
                N = length(ch1_x);
                if strcmp(value,'On')
                    for n= 1:N
                        if n<D+1
                            y2(n)=ch1_x(n);
                        end
                        if n>=D+1
                            y2(n)=ch1_x(n)+(1-alpha)*ch1_x(n-D)+alpha*y2(n-D);
                        end
                    end
                    assignin("base","ch2_x",y2);
                    assignin("base","ch3_x",y2);
                else
                    assignin("base","ch2_x",ch1_x);
                    assignin("base","ch3_x",ch1_x);
                end
%----------------------------DISTORTION1------------------------------------------------------------
                ch2_x=evalin("base","ch2_x");                
                gain=1;
                assignin("base","mix_DISTURTION1",app.DISTORTIONAMPLITUDKnob.Value);
                mix=evalin("base","mix_DISTURTION1");
                if strcmp(value2,'On')
                    q=ch2_x*gain;
                    z=sign(q).*(1-exp(-abs(q)));
                    y=mix*z+(1-mix)*ch2_x;
                    assignin("base","ch3_x",y);
                    assignin("base","ch4_x",y);
                else
                   assignin("base","ch3_x",ch2_x);
                   assignin("base","ch4_x",ch2_x);
                end
%---------------------------------------------------REVERBERATION1----------------------------------------------------
            ch3_x = evalin("base","ch3_x");
            [row,col] = size(ch3_x);
            if (col == 1)
                x1 = ch3_x(:,1);
                x2 = 0;
            elseif (col == 2)
                x1 = ch3_x(:,1);
                x2 = ch3_x(:,2);
            end
                        
            %-------------------------------------------------------------------------
            %*********************** GENERACIÓN DE IMPULSO ***************************
            %-------------------------------------------------------------------------
            % Definición de tamaños de memorias y efectos
            xh = zeros(1, 2500);
            xh(1) = 1;
            A = zeros(1, 44000);
            Adelay = 1000;
            ir = zeros(1, 2500);
            assignin("base","g_REVERB1",app.AMOUNTOFDELAYKnob.Value);
            g = evalin("base","g_REVERB1");
            if strcmp(value3,'On')
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n = 1:length(ir)
                    tmp = A(Adelay) + xh(n)*(-g);
                    A = [(tmp*g + xh(n))' A(1:length(A) - 1)];
                    ir(n) = tmp;
                end
                %-------------------------------------------------------------------------
                % Convolución entre los canales y el impulso creado
                ch1_y2 = conv (x1,ir);
                ch2_y2 = conv (x2,ir);
                
                % Concatenación de los dos canales
                if col==1
                    y3=ch1_y2;
                elseif col==2
                    y3 = horzcat(ch1_y2, ch2_y2);
                end
                

                assignin("base","ch4_x",y3);
                assignin("base","ch5_x",y3);
            else
                assignin("base","ch4_x",ch3_x);
                assignin("base","ch5_x",ch3_x);
            end
%-------------------------------VIBRATO1------------------------------------------

            ch4_x=evalin("base","ch4_x");
            [row,col] = size(ch4_x);
            if (col == 1)
                x3 = ch4_x(:,1);
                x4 = 0;
            elseif (col == 2)
                x3 = ch4_x(:,1);
                x4 = ch4_x(:,2);
            end
            
            % Definición de frecuencia de modulación
            Modfreq = app.MODULATIONFREQKnob.Value;
            assignin("base","Modfreq_VIBRATO1",Modfreq);
            % Definición de delay base
            
            Width = app.WIDTHKnob.Value;
            assignin("base","Width_VIBRATO1",Width);
            % Delay base de la entrada
            Delay=Width; 
            % Delay base por número de muestras
            DELAY=round(Delay*Fs); 
            % Ancho de modulación por número de muestras
            WIDTH=round(Width*Fs); 
            % Frecuencia de modulación por número de muestras
            MODFREQ=Modfreq/Fs; 
            if strcmp(value4,'On')
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_1=length(x3);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch1_y=zeros(size(x3));     
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_1-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x3(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch1_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_2=length(x4);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch2_y=zeros(size(x4));    
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_2-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x4(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch2_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                % Concatenación de los dos canales
                if col==1
                    y4=ch1_y;
                elseif col==2
                    y4 = horzcat(ch1_y, ch2_y);
                end
                assignin("base","ch5_x",y4);
                assignin("base","ch6_x",y4);
            else
                assignin("base","ch5_x",ch4_x);
                assignin("base","ch6_x",ch4_x);
            end
%--------------------------------------------------FLANGER-----------------------------------------------------
            ch5_x=evalin("base","ch5_x");
            assignin("base","r_FLANGER1",app.VARIATIONKnob.Value);
            r = evalin("base","r_FLANGER1");
            assignin("base","v_FLANGER1",app.RHYTHMKnob.Value);
            v = evalin("base","v_FLANGER1");
            if strcmp(value5,'On')
                [row,col] = size(ch5_x);
                if (col == 1)
                    x5 = ch5_x(:,1);
                    x6 = 0;
                elseif (col == 2)
                    x5 = ch5_x(:,1);
                    x6 = ch5_x(:,2);
                end
                
                % Aplicación de la función flange, guardandola en una nueva varaible
                ch1_y3 = flange(Fs,v,x5,r);
                ch2_y3 = flange(Fs,v,x6,r);
                
                % Concatenación de los dos canales
                if col==1
                    y5=ch1_y3;
                elseif col==2
                    y5 = horzcat(ch1_y3, ch2_y3);
                end
                assignin("base","ch6_x",y5);
            else
                assignin("base","ch6_x",ch5_x);
            end
            
%% ----------------------------------------------------------------------------------------ECO2------------------                
                ch7_x=evalin("base","ch7_x");                
                assignin("base","ECO2",app.ECOSwitch_2.Value);
                value6 = evalin("base","ECO2");
                assignin("base","DISTURTION2",app.DISTORTIONSwitch_2.Value);
                value7 = evalin("base","DISTURTION2");
                assignin("base","REVERB2",app.REVERBSwitch_2.Value);
                value8 = evalin("base","REVERB2");
                assignin("base","VIBRATO2",app.VIBRATOSwitch_2.Value);
                value9 = evalin("base","VIBRATO2");
                assignin("base","FLANGER2",app.FLANGERSwitch_2.Value);
                value10 = evalin("base","FLANGER2");
                assignin("base","alpha_ECO2",app.ECOAMPLITUDKnob_2.Value);
                alpha1=evalin("base","alpha_ECO2");
                D1=.25*Fs;
                y6 = zeros(size(ch7_x));
                xD2=0;
                yD2=0;
                N = length(ch7_x);
                if strcmp(value6,'On')
                    for n= 1:N
                        if n<D1+1
                            y6(n)=ch7_x(n);
                        end
                        if n>=D1+1
                            y6(n)=ch7_x(n)+(1-alpha1)*ch7_x(n-D1)+alpha1*y6(n-D1);
                        end
                    end
                    assignin("base","ch8_x",y6);
                    assignin("base","ch9_x",y6);
                else
                    assignin("base","ch8_x",ch7_x);
                    assignin("base","ch9_x",ch7_x);
                end
%----------------------------DISTORTION2------------------------------------------------------------
                ch8_x=evalin("base","ch8_x");
                gain=1;
                assignin("base","mix_DISTURTION2",app.DISTORTIONAMPLITUDKnob_2.Value);
                mix2=evalin("base","mix_DISTURTION2");
                if strcmp(value7,'On')
                    q=ch7_x*gain;
                    z=sign(q).*(1-exp(-abs(q)));
                    y=mix2*z+(1-mix2)*ch7_x;
                    assignin("base","ch9_x",y);
                    assignin("base","ch10_x",y);
                else
                   assignin("base","ch9_x",ch8_x);
                   assignin("base","ch10_x",ch8_x);
                end
%---------------------------------------------------REVERBERATION2----------------------------------------------------
            ch9_x = evalin("base","ch9_x");
            [row,col] = size(ch9_x);
            if (col == 1)
                x1 = ch9_x(:,1);
                x2 = 0;
            elseif (col == 2)
                x1 = ch9_x(:,1);
                x2 = ch9_x(:,2);
            end
            %-------------------------------------------------------------------------
            %*********************** GENERACIÓN DE IMPULSO ***************************
            %-------------------------------------------------------------------------
            % Definición de tamaños de memorias y efectos
            xh = zeros(1, 2500);
            xh(1) = 1;
            A = zeros(1, 44000);
            Adelay = 1000;
            ir = zeros(1, 2500);
            assignin("base","g_REVERB2",app.AMOUNTOFDELAYKnob_2.Value);
            g2 = evalin("base","g_REVERB2");
            if strcmp(value8,'On')
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n = 1:length(ir)
                    tmp = A(Adelay) + xh(n)*(-g2);
                    A = [(tmp*g2 + xh(n))' A(1:length(A) - 1)];
                    ir(n) = tmp;
                end
                %-------------------------------------------------------------------------
                % Convolución entre los canales y el impulso creado
                ch1_y2 = conv (x1,ir);
                ch2_y2 = conv (x2,ir);
                
                % Concatenación de los dos canales
                if col == 1
                    y3=ch1_y2;
                elseif col==2
                    y3 = horzcat(ch1_y2, ch2_y2);
                end
                assignin("base","ch10_x",y3);
                assignin("base","ch11_x",y3);
            else
                assignin("base","ch10_x",ch9_x);
                assignin("base","ch11_x",ch9_x);
            end
%-------------------------------VIBRATO2------------------------------------------

            ch10_x=evalin("base","ch10_x");
            [row,col] = size(ch10_x);
            if (col == 1)
                x3 = ch10_x(:,1);
                x4 = 0;
            elseif (col == 2)
                x3 = ch10_x(:,1);
                x4 = ch10_x(:,2);
            end
            % Definición de frecuencia de modulación 
            assignin("base","Modfreq_VIBRATO2",app.MODULATIONFREQKnob_2.Value)
            Modfreq2 = evalin("base","Modfreq_VIBRATO2"); 
            % Definición de delay base
            assignin("base","Width_VIBRATO2",app.WIDTHKnob_2.Value);
            Width2 = evalin("base","Width_VIBRATO2"); 
            % Delay base de la entrada
            Delay=Width2; 
            % Delay base por número de muestras
            DELAY=round(Delay*Fs); 
            % Ancho de modulación por número de muestras
            WIDTH=round(Width2*Fs); 
            % Frecuencia de modulación por número de muestras
            MODFREQ=Modfreq2/Fs; 
            if strcmp(value9,'On')
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_1=length(x3);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch1_y=zeros(size(x3));     
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_1-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x3(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch1_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_2=length(x4);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch2_y=zeros(size(x4));    
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_2-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x4(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch2_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                % Concatenación de los dos canales
                if col == 1
                    y4=ch1_y;
                elseif col==2
                    y4 = horzcat(ch1_y, ch2_y);
                end               
                assignin("base","ch11_x",y4);
                assignin("base","ch12_x",y4);
            else
                assignin("base","ch11_x",ch10_x);
                assignin("base","ch12_x",ch10_x);
            end
%--------------------------------------------------FLANGER2-----------------------------------------------------
            ch11_x=evalin("base","ch11_x");            
            assignin("base","r_FLANGER2",app.VARIATIONKnob_2.Value);
            r2 = evalin("base","r_FLANGER2");
            assignin("base","v_FLANGER2",app.RHYTHMKnob_2.Value);
            v2 = evalin("base","v_FLANGER2");
            if strcmp(value10,'On')
                [row,col] = size(ch11_x);
                if (col == 1)
                    x5 = ch11_x(:,1);
                    x6 = 0;
                elseif (col == 2)
                    x5 = ch11_x(:,1);
                    x6 = ch11_x(:,2);
                end
                
                % Aplicación de la función flange, guardandola en una nueva varaible
                ch1_y3 = flange(Fs,v2,x5,r2);
                ch2_y3 = flange(Fs,v2,x6,r2);
                
                % Concatenación de los dos canales
                if col == 1
                    y5=ch1_y3;
                elseif col==2
                    y5 = horzcat(ch1_y3, ch2_y3);
                end                
                assignin("base","ch12_x",y5);
            else
                assignin("base","ch12_x",ch11_x);
            end
%%---------------------------------AMPLITUD Y SUMA---------------------------------------------------------            
                ch2_x=evalin("base","ch2_x");
                ch3_x=evalin("base","ch3_x");
                ch4_x=evalin("base","ch4_x");
                ch5_x=evalin("base","ch5_x");
                ch6_x=evalin("base","ch6_x");
                ch8_x=evalin("base","ch8_x");
                ch9_x=evalin("base","ch9_x");
                ch10_x=evalin("base","ch10_x");
                ch11_x=evalin("base","ch11_x");
                ch12_x=evalin("base","ch12_x");
                assignin("base","VOLUME1",app.VOLUME1Knob.Value);
                V1=evalin("base","VOLUME1");
                assignin("base","VOLUME2",app.VOLUME2Knob.Value);
                V2=evalin("base","VOLUME2");
                [row,col]=size(x);
                if col==1
                    if length(ch6_x) == length(ch12_x)
                    y=V1.*ch6_x+V2.*ch12_x;
                    end
                    if length(ch6_x) > length(ch12_x)
                        L=length(ch6_x)-length(ch12_x);
                        ch12_x=[ch12_x;zeros(L,1)];
                        y=V1.*ch6_x+V2.*ch12_x;
                    end
                    if length(ch12_x) > length(ch6_x)
                        L=length(ch12_x)-length(ch6_x);
                        ch6_x=[ch6_x;zeros(L,1)];
                        y=V1.*ch6_x+V2.*ch12_x;
                    end
                elseif col==2
                    if length(ch6_x) == length(ch12_x)
                    y=V1.*ch6_x+V2.*ch12_x;
                    end
                    if length(ch6_x) > length(ch12_x)
                        L=length(ch6_x)-length(ch12_x);
                        ch12_x=[ch12_x;zeros(L,2)];
                        y=V1.*ch6_x+V2.*ch12_x;
                    end
                    if length(ch12_x) > length(ch6_x)
                        L=length(ch12_x)-length(ch6_x);
                        ch6_x=[ch6_x;zeros(L,2)];
                        y=V1.*ch6_x+V2.*ch12_x;
                    end
                end
                
                y = normalize(y,'range',[-1,1]);
                assignin("base","y",y);
                soundsc(y,Fs);                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MUSICTab = uitab(app.TabGroup);
            app.MUSICTab.Title = 'MUSIC';
            app.UploadFileLabel = uilabel(app.MUSICTab);
            app.UploadFileLabel.Text = 'Upload File';
            app.SelectsongButton = uibutton(app.MUSICTab, 'push');
            app.SelectsongButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsongButtonPushed, true);
            app.SelectsongButton.Text = 'Select song';
            app.EditField = uieditfield(app.MUSICTab, 'text');
            app.PlayButton_2 = uibutton(app.MUSICTab, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.PlayButton_2.Icon = 'play_stop.png';
            app.PlayButton_2.Text = 'Play';
            app.PlayOriginalAudioLabel = uilabel(app.MUSICTab);
            app.PlayOriginalAudioLabel.Text = {'Play Original Audio'; ''};
            app.FILTERTab = uitab(app.TabGroup);
            app.FILTERTab.Title = 'FILTER';
            app.FILTERButton = uibutton(app.FILTERTab, 'push');
            app.FILTERButton.ButtonPushedFcn = createCallbackFcn(app, @FILTERButtonPushed, true);
            app.FILTERButton.Text = 'FILTER';
            app.ApplyFilterLabel = uilabel(app.FILTERTab);
            app.ApplyFilterLabel.Text = 'Apply Filter';
            app.SelectFCL1EditFieldLabel = uilabel(app.FILTERTab);
            app.SelectFCL1EditFieldLabel.Text = 'Select FCL 1';
            app.SelectFCL1EditField = uieditfield(app.FILTERTab, 'text');
            app.SelectFCH1Label = uilabel(app.FILTERTab);
            app.SelectFCH1Label.Text = 'Select FCH 1';
            app.SelectFCH1EditField = uieditfield(app.FILTERTab, 'text');
            app.SelectFCL2EditFieldLabel = uilabel(app.FILTERTab);
            app.SelectFCL2EditFieldLabel.Text = 'Select FCL 2';
            app.SelectFCL2EditField = uieditfield(app.FILTERTab, 'text');
            app.SelectFCH2EditField_2Label = uilabel(app.FILTERTab);
            app.SelectFCH2EditField_2Label.Text = 'Select FCH 2';
            app.SelectFCH2EditField = uieditfield(app.FILTERTab, 'text');
            app.GRAPHButton = uibutton(app.FILTERTab, 'push');
            app.GRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButtonPushed, true);
            app.GRAPHButton.Text = 'GRAPH.';
            app.TextArea = uitextarea(app.FILTERTab);
            app.TextArea.Tooltip = {''};
            app.EFFECT1Tab = uitab(app.TabGroup);
            app.EFFECT1Tab.Title = 'EFFECT 1';
            app.FIRSTBANDLabel = uilabel(app.EFFECT1Tab);
            app.FIRSTBANDLabel.Text = 'FIRST BAND';
            app.DISTORTIONAMPLITUDKnobLabel = uilabel(app.EFFECT1Tab);
            app.DISTORTIONAMPLITUDKnobLabel.Text = {'DISTORTION'; 'AMPLITUD'};
            app.DISTORTIONAMPLITUDKnob = uiknob(app.EFFECT1Tab, 'continuous');
            app.DISTORTIONAMPLITUDKnob.Limits = [0 10];
            app.AMOUNTOFDELAYKnobLabel = uilabel(app.EFFECT1Tab);
            app.AMOUNTOFDELAYKnobLabel.Text = {'AMOUNT OF'; 'DELAY'};
            app.AMOUNTOFDELAYKnob = uiknob(app.EFFECT1Tab, 'continuous');
            app.AMOUNTOFDELAYKnob.Limits = [0 2000];
            app.VARIATIONKnobLabel = uilabel(app.EFFECT1Tab);
            app.VARIATIONKnobLabel.Text = 'VARIATION';
            app.VARIATIONKnob = uiknob(app.EFFECT1Tab, 'continuous');
            app.VARIATIONKnob.Limits = [0.001 0.009];
            app.VARIATIONKnob.Value = 0.001;
            app.MODULATIONFREQKnobLabel = uilabel(app.EFFECT1Tab);
            app.MODULATIONFREQKnobLabel.Text = {'MODULATION'; 'FREQ.'};
            app.MODULATIONFREQKnob = uiknob(app.EFFECT1Tab, 'continuous');
            app.MODULATIONFREQKnob.Limits = [0 10];
            app.ECOAMPLITUDLabel = uilabel(app.EFFECT1Tab);
            app.ECOAMPLITUDLabel.Text = {'ECO'; 'AMPLITUD'};
            app.ECOAMPLITUDKnob = uiknob(app.EFFECT1Tab, 'continuous');
            app.ECOAMPLITUDKnob.Limits = [0 1];
            app.RHYTHMLabel = uilabel(app.EFFECT1Tab);
            app.RHYTHMLabel.Text = 'RHYTHM';
            app.RHYTHMKnob = uiknob(app.EFFECT1Tab, 'continuous');
            app.RHYTHMKnob.Limits = [0 1];
            app.WIDTHKnobLabel = uilabel(app.EFFECT1Tab);
            app.WIDTHKnobLabel.Text = 'WIDTH';
            app.WIDTHKnob = uiknob(app.EFFECT1Tab, 'continuous');
            app.WIDTHKnob.Limits = [0.001 0.009];
            app.WIDTHKnob.Value = 0.001;
            app.ECOSwitch_3Label = uilabel(app.EFFECT1Tab);
            app.ECOSwitch_3Label.Text = 'ECO';
            app.ECOSwitch = uiswitch(app.EFFECT1Tab, 'toggle');
            app.DISTORTIONSwitch_3Label = uilabel(app.EFFECT1Tab);
            app.DISTORTIONSwitch_3Label.Text = 'DISTORTION';
            app.DISTORTIONSwitch = uiswitch(app.EFFECT1Tab, 'toggle');
            app.REVERBSwitch_3Label = uilabel(app.EFFECT1Tab);
            app.REVERBSwitch_3Label.Text = 'REVERB.';
            app.REVERBSwitch = uiswitch(app.EFFECT1Tab, 'toggle');
            app.VIBRATOSwitch_3Label = uilabel(app.EFFECT1Tab);
            app.VIBRATOSwitch_3Label.Text = 'VIBRATO';
            app.VIBRATOSwitch = uiswitch(app.EFFECT1Tab, 'toggle');
            app.FLANGERSwitchLabel = uilabel(app.EFFECT1Tab);
            app.FLANGERSwitchLabel.Text = 'FLANGER';
            app.FLANGERSwitch = uiswitch(app.EFFECT1Tab, 'toggle');
            app.EFFECT2Tab = uitab(app.TabGroup);
            app.EFFECT2Tab.Title = 'EFFECT 2';
            app.SECONDBANDLabel = uilabel(app.EFFECT2Tab);
            app.SECONDBANDLabel.Text = 'SECOND BAND';
            app.ECOSwitch_2Label = uilabel(app.EFFECT2Tab);
            app.ECOSwitch_2Label.Text = 'ECO';
            app.ECOSwitch_2 = uiswitch(app.EFFECT2Tab, 'toggle');
            app.DISTORTIONSwitch_2Label = uilabel(app.EFFECT2Tab);
            app.DISTORTIONSwitch_2Label.Text = 'DISTORTION';
            app.DISTORTIONSwitch_2 = uiswitch(app.EFFECT2Tab, 'toggle');
            app.REVERBSwitch_2Label = uilabel(app.EFFECT2Tab);
            app.REVERBSwitch_2Label.Text = 'REVERB.';
            app.REVERBSwitch_2 = uiswitch(app.EFFECT2Tab, 'toggle');
            app.VIBRATOSwitch_2Label = uilabel(app.EFFECT2Tab);
            app.VIBRATOSwitch_2Label.Text = 'VIBRATO';
            app.VIBRATOSwitch_2 = uiswitch(app.EFFECT2Tab, 'toggle');
            app.FLANGERSwitch_2Label = uilabel(app.EFFECT2Tab);
            app.FLANGERSwitch_2Label.Text = 'FLANGER';
            app.FLANGERSwitch_2 = uiswitch(app.EFFECT2Tab, 'toggle');
            app.DISTORTIONAMPLITUDKnob_2Label = uilabel(app.EFFECT2Tab);
            app.DISTORTIONAMPLITUDKnob_2Label.Text = {'DISTORTION'; 'AMPLITUD'};
            app.DISTORTIONAMPLITUDKnob_2 = uiknob(app.EFFECT2Tab, 'continuous');
            app.DISTORTIONAMPLITUDKnob_2.Limits = [0 10];
            app.AMOUNTOFDELAYKnob_2Label = uilabel(app.EFFECT2Tab);
            app.AMOUNTOFDELAYKnob_2Label.Text = {'AMOUNT OF'; 'DELAY'};
            app.AMOUNTOFDELAYKnob_2 = uiknob(app.EFFECT2Tab, 'continuous');
            app.AMOUNTOFDELAYKnob_2.Limits = [0 2000];
            app.MODULATIONFREQKnob_2Label = uilabel(app.EFFECT2Tab);
            app.MODULATIONFREQKnob_2Label.Text = {'MODULATION'; 'FREQ.'};
            app.MODULATIONFREQKnob_2 = uiknob(app.EFFECT2Tab, 'continuous');
            app.MODULATIONFREQKnob_2.Limits = [0 10];
            app.ECOAMPLITUDKnob_2Label = uilabel(app.EFFECT2Tab);
            app.ECOAMPLITUDKnob_2Label.Text = {'ECO'; 'AMPLITUD'};
            app.ECOAMPLITUDKnob_2 = uiknob(app.EFFECT2Tab, 'continuous');
            app.ECOAMPLITUDKnob_2.Limits = [0 1];
            app.VARIATIONKnob_2Label = uilabel(app.EFFECT2Tab);
            app.VARIATIONKnob_2Label.Text = 'VARIATION';
            app.VARIATIONKnob_2 = uiknob(app.EFFECT2Tab, 'continuous');
            app.VARIATIONKnob_2.Limits = [0.001 0.009];
            app.VARIATIONKnob_2.Value = 0.001;
            app.RHYTHMKnob_2Label = uilabel(app.EFFECT2Tab);
            app.RHYTHMKnob_2Label.Text = 'RHYTHM';
            app.RHYTHMKnob_2 = uiknob(app.EFFECT2Tab, 'continuous');
            app.RHYTHMKnob_2.Limits = [0 1];
            app.WIDTHKnob_2Label = uilabel(app.EFFECT2Tab);
            app.WIDTHKnob_2Label.Text = 'WIDTH';
            app.WIDTHKnob_2 = uiknob(app.EFFECT2Tab, 'continuous');
            app.WIDTHKnob_2.Limits = [0.001 0.009];
            app.WIDTHKnob_2.Value = 0.001;
            app.REPRODUCTIONTab = uitab(app.TabGroup);
            app.REPRODUCTIONTab.Title = 'REPRODUCTION';
            app.PlayMusicLabel = uilabel(app.REPRODUCTIONTab);
            app.PlayMusicLabel.Text = {'Play Music'; ''};
            app.SaveDataLabel = uilabel(app.REPRODUCTIONTab);
            app.SaveDataLabel.Text = {'Save Data'; ''};
            app.SaveAudioLabel = uilabel(app.REPRODUCTIONTab);
            app.SaveAudioLabel.Text = 'Save Audio';
            app.AudioNameEditFieldLabel = uilabel(app.REPRODUCTIONTab);
            app.AudioNameEditFieldLabel.Text = 'Audio Name';
            app.AudioNameEditField = uieditfield(app.REPRODUCTIONTab, 'text');
            app.DataNameEditFieldLabel = uilabel(app.REPRODUCTIONTab);
            app.DataNameEditFieldLabel.Text = 'Data Name';
            app.DataNameEditField = uieditfield(app.REPRODUCTIONTab, 'text');
            app.VOLUME1KnobLabel = uilabel(app.REPRODUCTIONTab);
            app.VOLUME1KnobLabel.Text = 'VOLUME 1';
            app.VOLUME1Knob = uiknob(app.REPRODUCTIONTab, 'continuous');
            app.VOLUME2KnobLabel = uilabel(app.REPRODUCTIONTab);
            app.VOLUME2KnobLabel.Text = 'VOLUME 2';
            app.VOLUME2Knob = uiknob(app.REPRODUCTIONTab, 'continuous');
            app.SaveAudioButton = uibutton(app.REPRODUCTIONTab, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Icon = 'save.png';
            app.SaveAudioButton.Text = 'Save Audio';
            app.SaveDataButton = uibutton(app.REPRODUCTIONTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Icon = 'save.png';
            app.SaveDataButton.Text = {'Save Data'; ''};
            app.PlayButton = uibutton(app.REPRODUCTIONTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'play_stop.png';
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Efectos_de_Audio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end