classdef musica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectsongButton            matlab.ui.control.Button
        EcoSwitchLabel              matlab.ui.control.Label
        EcoSwitch                   matlab.ui.control.ToggleSwitch
        DistortionSwitchLabel       matlab.ui.control.Label
        DistortionSwitch            matlab.ui.control.ToggleSwitch
        ReverbSwitchLabel           matlab.ui.control.Label
        ReverbSwitch                matlab.ui.control.ToggleSwitch
        VibratoSwitchLabel          matlab.ui.control.Label
        VibratoSwitch               matlab.ui.control.ToggleSwitch
        FlangerSwitchLabel          matlab.ui.control.Label
        FlangerSwitch               matlab.ui.control.ToggleSwitch
        EcoSwitch_2Label            matlab.ui.control.Label
        EcoSwitch_2                 matlab.ui.control.ToggleSwitch
        DistortionSwitch_2Label     matlab.ui.control.Label
        DistortionSwitch_2          matlab.ui.control.ToggleSwitch
        ReverbSwitch_2Label         matlab.ui.control.Label
        ReverbSwitch_2              matlab.ui.control.ToggleSwitch
        VibratoSwitch_2Label        matlab.ui.control.Label
        VibratoSwitch_2             matlab.ui.control.ToggleSwitch
        FlangerSwitch_2Label        matlab.ui.control.Label
        FlangerSwitch_2             matlab.ui.control.ToggleSwitch
        Efectoscanal1Label          matlab.ui.control.Label
        Efectoscanal2Label          matlab.ui.control.Label
        EfectosdeSonidoLabel        matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        Step1Label                  matlab.ui.control.Label
        Step2Label                  matlab.ui.control.Label
        SelectFCL1EditFieldLabel    matlab.ui.control.Label
        SelectFCL1EditField         matlab.ui.control.EditField
        SelectFCH2EditFieldLabel    matlab.ui.control.Label
        SelectFCH2EditField         matlab.ui.control.EditField
        SelectFCL2EditFieldLabel    matlab.ui.control.Label
        SelectFCL2EditField         matlab.ui.control.EditField
        SelectFCH2EditField_2Label  matlab.ui.control.Label
        SelectFCH2EditField_2       matlab.ui.control.EditField
        ApplyfilterButton           matlab.ui.control.Button
        Step3Label                  matlab.ui.control.Label
        Volumechannel1KnobLabel     matlab.ui.control.Label
        Volumechannel1Knob          matlab.ui.control.Knob
        Volumechannel2KnobLabel     matlab.ui.control.Label
        Volumechannel2Knob          matlab.ui.control.Knob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectsongButton
        function SelectsongButtonPushed(app, event)
            [file,path]=uigetfile('.wav');
            if file==0
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                [x,Fs]=audioread(file);
                %ch1_x=x(:,1);
                %ch2_x=x(:,2);
                assignin("base","x",x);
                %assignin("base","ch1_x",ch1_x);
                %assignin("base","ch2_x",ch2_x);
                assignin("base","Fs",Fs);
            end
        end

        % Value changed function: EcoSwitch
        function EcoSwitchValueChanged(app, event)
            value = app.EcoSwitch.Value;
            ch1_x = evalin("base","ch1_x");
            Fs=evalin("base","Fs");
            alpha=0.5;
            D=0.25*Fs;
            y2 = zeros(size(ch1_x));
            xD2=0;
            yD2=0;
            N = length(ch1_x);
            assignin("base","ch1_x",ch1_x);
            if strcmp(value,'On')
                for n= 1:N
                    if n<D+1
                        y2(n)=ch1_x(n);
                    end
                    if n>=D+1
                        y2(n)=ch1_x(n)+(1-alpha)*ch1_x(n-D)+alpha*y2(n-D);
                    end
                end
                assignin("base","ch2_x",y2);
            else
                assignin("base","ch2_x",ch1_x);
            end
        end

        % Value changed function: EcoSwitch_2
        function EcoSwitch_2ValueChanged(app, event)
            value = app.EcoSwitch_2.Value;
            ch2_x = evalin("base","ch2_x");
            alpha=0.5;
            D=0.25*Fs;
            y2 = zeros(size(ch2_x));
            xD2=0;
            yD2=0;
            N = length(ch2_x);
            if strcmp(value,'On')
                for n= 1:N
                    if n<D+1
                        y2(n)=x(n);
                    end
                    if n>=D+1
                        y2(n)=ch2_x(n)+(1-alpha)*ch_x(n-D)+alpha*y2(n-D);
                    end
                end
            else
                clear soundsc
            end
        end

        % Value changed function: DistortionSwitch
        function DistortionSwitchValueChanged(app, event)
            value = app.DistortionSwitch.Value;
            ch1_x = evalin("base","ch1_x");
            gain=1;
            mix=5;
            if strcmp(value,'On')
                q=ch1_x*gain;
                z=sign(q).*(1-exp(-abs(q)));
                y=mix*z+(1-mix)*ch1_x;
                assignin("base","ch3_x",y);
            else
               assignin("base","ch3_x",ch1_x);
            end
        end

        % Value changed function: ReverbSwitch
        function ReverbSwitchValueChanged(app, event)
            value = app.ReverbSwitch.Value;
            x = evalin("base","ch1_x");
            % Definición de canales
            ch1_x = x(:,1);
            ch2_x = x(:,2);
            if strcmp(value,"On")
                %-------------------------------------------------------------------------
                %*********************** GENERACIÓN DE IMPULSO ***************************
                %-------------------------------------------------------------------------
                % Definición de tamaños de memorias y efectos
                xh = zeros(1, 2500);
                xh(1) = 1;
                A = zeros(1, 44000);
                Adelay = 1000;
                ir = zeros(1, 2500);
                g = 0.7;
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n = 1:length(ir)
                    tmp = A(Adelay) + xh(n)*(-g);
                    A = [(tmp*g + xh(n))' A(1:length(A) - 1)];
                    ir(n) = tmp;
                end
                %-------------------------------------------------------------------------
                % Convolución entre los canales y el impulso creado
                ch1_y = conv (ch1_x,ir);
                ch2_y = conv (ch2_x,ir);
                
                % Concatenación de los dos canales
                y = horzcat(ch1_y, ch2_y);
                assignin("base","ch4_x",y);
            else
                assignin("base","ch4_x",x);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ch2_x = evalin("base","ch2_x");
            ch3_x = evalin("base","ch3_x");
            ch4_x = evalin("base","ch4_x");
            ch5_x = evalin("base","ch5_x");
            ch6_x = evalin("base","ch6_x");
            y=ch6_x;
            Fs=evalin("base","Fs");
            centered_y = y - repmat(mean(y), size(y,1), 1);
            maxy = max(abs(centered_y(:)));
            scaled_centered_y = centered_y ./ maxy;
            assignin("base","scaled_centered_y",scaled_centered_y);
            player = audioplayer (scaled_centered_y, Fs);
            %play (player);
            soundsc(y,Fs);
%             else
%                 %stop (player);
            
        end

        % Value changed function: SelectFCL1EditField
        function SelectFCL1EditFieldValueChanged(app, event)
            value = app.SelectFCL1EditField.Value;
            FCL1=double(convertCharsToStrings(value));
            assignin("base","FCL1",FCL1);
        end

        % Value changed function: SelectFCH2EditField
        function SelectFCH2EditFieldValueChanged(app, event)
            value = app.SelectFCH2EditField.Value;
            FCH1=double(convertCharsToStrings(value));
            assignin("base","FCH1",FCH1);
        end

        % Value changed function: SelectFCL2EditField
        function SelectFCL2EditFieldValueChanged(app, event)
            value = app.SelectFCL2EditField.Value;
            FCL2=double(convertCharsToStrings(value));
            assignin("base","FCL2",FCL2);
        end

        % Value changed function: SelectFCH2EditField_2
        function SelectFCH2EditField_2ValueChanged(app, event)
            value = app.SelectFCH2EditField_2.Value;
            FCH2=double(convertCharsToStrings(value));
            assignin("base","FCH2",FCH2);
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)
            x=evalin("base","x");
            Fs=evalin("base","Fs");
            % Definición del Periodo de Muestreo
            Ts = 1/Fs;
            % Reproducción de la entrada
            centered_x = x - repmat(mean(x), size(x,1), 1);
            maxx = max(abs(centered_x(:)));
            scaled_centered_x = centered_x ./ maxx;
            player_x = audioplayer (scaled_centered_x, Fs);
            %play (player_x);
            %stop (player_x);
            %% Diseño de Filtro  1 por Método Indirecto
            syms s z
            % Prototipo analógico
            H_1(s) = 1 / (s^2+sqrt(2)*s+1);
            % Frecuencia y período de muestreo
            Fl_1 = evalin("base","FCL1");
            Fh_1 = evalin("base","FCH1");
            % Frecuencias de corte digitales requeridas
            wl_1 = (2*pi*Fl_1)*Ts;
            wh_1 = (2*pi*Fh_1)*Ts;
            % Encontramos las frecuencias analógicas deseadas Wl y Wu mediante 
            % frequency pre-warping
            Wl_1 = (2/Ts)*tan((wl_1)/2);
            Wh_1 = (2/Ts)*tan((wh_1)/2);
            % Generamos el filtro deseado a partir del prototipo
            Hbpf_a_1(s) = H_1(1*((s^2+Wl_1*Wh_1)/((Wh_1-Wl_1)*s)));
            % Generamos el filtro digital mediante la transformación bilineal
            Hbpf_1(z) = Hbpf_a_1((2/Ts)*((z-1)/(z+1)));
            % Obtenemos el numerador y denominador
            [N_1, D_1] = numden(simplifyFraction(Hbpf_1));
            % Obtenemos los coeficientes del numerador y denominador
            b_1 = fliplr(double(coeffs(N_1)));
            a_1 = fliplr(double(coeffs(D_1)));
            % Normalizamos
            b_1 = b_1 / a_1(1);
            a_1 = a_1 / a_1(1);
            % Se observa la función de transferencia
            Hf_1 = tf(b_1, a_1, Ts);
            % Se observa el filtro diseñado
            fvtool(b_1,a_1,'Fs',Fs);
            %{    
            % Aplicación de Ecuación de Diferencias
            N = length(x);
            y = zeros(size(x));           %Definición de vector de ceros para inicializar
            b2 = b(1);
            b1 = b(2);
            b4 = b(3);
            a1 = a(2);
            a2 = a(3); 
            a3 = a(4);
            a4 = a(5);
            xh = [0, 0, 0, 0];
            % Ciclo for para muestrear los datos de la ecuación de diferencias
            for n = 1:N
            xh_new = x(n) - a1*xh(1) - a2*xh(2) - a3*xh(3) - a4*xh(4);
            y(n) = b1*xh(1) + b2*xh(2) + b4*xh(4);
            xh = [xh_new, xh(1), xh(2), xh(3)];
            end
            %}
            y_1 = filter(b_1, a_1, x);
            assignin("base","ch1_x",y_1);
            assignin("base","ch2_x",y_1);
            assignin("base","ch3_x",y_1);
            assignin("base","ch4_x",y_1);
            assignin("base","ch5_x",y_1);
            % Reproducción de la salida
            centered_y_1 = y_1 - repmat(mean(y_1), size(y_1,1), 1);
            maxy_1 = max(abs(centered_y_1(:)));
            scaled_centered_y_1 = centered_y_1 ./ maxy_1;
            player_y_1 = audioplayer (scaled_centered_y_1, Fs);
            %play (player_y_1);
            %stop (player_y_1);
            %% Graficas de filtrado para Primera Señal
            %Canal 1 de x
            ch1_x = x(:,1);
            yX1_x1 = fft(ch1_x);
            yX1_bi_x1 = abs(yX1_x1/length(ch1_x));
            yX1_uni_x1 = yX1_bi_x1(1:length(ch1_x)/2+1);
            yX1_uni_x1(2:end-1) = 2*yX1_uni_x1(2:end-1);
            axis_x_2 = Fs*(0:(length(ch1_x)/2))/length(ch1_x);
            %Canal 2 de x
            ch2_x = x(:,2);
            yX1_x2 = fft(ch2_x);
            yX1_bi_x2 = abs(yX1_x2/length(ch2_x));
            yX1_uni_x2 = yX1_bi_x2(1:length(ch2_x)/2+1);
            yX1_uni_x2(2:end-1) = 2*yX1_uni_x2(2:end-1);
            %Canal 1 de y_1
            ch1_y_1 = y_1(:,1);
            yX1_y1_1 = fft(ch1_y_1);
            yX1_bi_y1_1 = abs(yX1_y1_1/length(ch1_y_1));
            yX1_uni_y1_1 = yX1_bi_y1_1(1:length(ch1_y_1)/2+1);
            yX1_uni_y1_1(2:end-1) = 2*yX1_uni_y1_1(2:end-1);
            %Canal 2 de y_1
            ch2_y_1 = y_1(:,2);
            yX1_y2_1 = fft(ch2_y_1);
            yX1_bi_y2_1 = abs(yX1_y2_1/length(ch2_y_1));
            yX1_uni_y2_1 = yX1_bi_y2_1(1:length(ch2_y_1)/2+1);
            yX1_uni_y2_1(2:end-1) = 2*yX1_uni_y2_1(2:end-1);
            
            %Gráfica
            figure(2);
            clf;
            subplot(2, 2, 1);
            plot(axis_x_2(1:44100), yX1_uni_x1(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 1 de x.');
            subplot(2, 2, 2);
            plot(axis_x_2(1:44100), yX1_uni_x2(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 2 de x.');
            subplot(2, 2, 3);
            plot(axis_x_2(1:44100), yX1_uni_y1_1(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 1 de y_1.');
            subplot(2, 2, 4);
            plot(axis_x_2(1:44100), yX1_uni_y2_1(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 2 de y_1.');
            
            %% Diseño de Filtro 2 por Método Indirecto
            syms s z
            % Prototipo analógico
            H_2(s) = 1 / (s^2+sqrt(2)*s+1);
            % Frecuencia y período de muestreo
            Fl_2 = evalin("base","FCL2");
            Fh_2 = evalin("base","FCH2");
            % Frecuencias de corte digitales requeridas
            wl_2 = (2*pi*Fl_2)*Ts;
            wh_2 = (2*pi*Fh_2)*Ts;
            % Encontramos las frecuencias analógicas deseadas Wl y Wu mediante 
            % frequency pre-warping
            Wl_2 = (2/Ts)*tan((wl_2)/2);
            Wh_2 = (2/Ts)*tan((wh_2)/2);
            % Generamos el filtro deseado a partir del prototipo
            Hbpf_a_2(s) = H_2(1*((s^2+Wl_2*Wh_2)/((Wh_2-Wl_2)*s)));
            % Generamos el filtro digital mediante la transformación bilineal
            Hbpf_2(z) = Hbpf_a_2((2/Ts)*((z-1)/(z+1)));
            % Obtenemos el numerador y denominador
            [N_2, D_2] = numden(simplifyFraction(Hbpf_2));
            % Obtenemos los coeficientes del numerador y denominador
            b_2 = fliplr(double(coeffs(N_2)));
            a_2 = fliplr(double(coeffs(D_2)));
            % Normalizamos
            b_2 = b_2 / a_2(1);
            a_2 = a_2 / a_2(1);
            % Se observa la función de transferencia
            Hf_2 = tf(b_2, a_2, Ts);
            % Se observa el filtro diseñado
            fvtool(b_2,a_2,'Fs',Fs);
            %{    
            % Aplicación de Ecuación de Diferencias
            N = length(x);
            y = zeros(size(x));           %Definición de vector de ceros para inicializar
            b2 = b(1);
            b1 = b(2);
            b4 = b(3);
            a1 = a(2);
            a2 = a(3); 
            a3 = a(4);
            a4 = a(5);
            xh = [0, 0, 0, 0];
            % Ciclo for para muestrear los datos de la ecuación de diferencias
            for n = 1:N
            xh_new = x(n) - a1*xh(1) - a2*xh(2) - a3*xh(3) - a4*xh(4);
            y(n) = b1*xh(1) + b2*xh(2) + b4*xh(4);
            xh = [xh_new, xh(1), xh(2), xh(3)];
            end
            %}
            y_2 = filter(b_2, a_2, x);
            assignin("base","ch7_x",y_2);
            % Reproducción de la salida
            centered_y_2 = y_2 - repmat(mean(y_2), size(y_2,1), 1);
            maxy_2 = max(abs(centered_y_2(:)));
            scaled_centered_y_2 = centered_y_2 ./ maxy_2;
            player_y_2 = audioplayer (scaled_centered_y_2, Fs);
            %play (player_y_2);
            %stop (player_y_2);
            %% Graficas de filtrado para Segunda Señal
            %Canal 1 de x
            ch1_x = x(:,1);
            yX1_x1 = fft(ch1_x);
            yX1_bi_x1 = abs(yX1_x1/length(ch1_x));
            yX1_uni_x1 = yX1_bi_x1(1:length(ch1_x)/2+1);
            yX1_uni_x1(2:end-1) = 2*yX1_uni_x1(2:end-1);
            axis_x_2 = Fs*(0:(length(ch1_x)/2))/length(ch1_x);
            %Canal 2 de x
            ch2_x = x(:,2);
            yX1_x2 = fft(ch2_x);
            yX1_bi_x2 = abs(yX1_x2/length(ch2_x));
            yX1_uni_x2 = yX1_bi_x2(1:length(ch2_x)/2+1);
            yX1_uni_x2(2:end-1) = 2*yX1_uni_x2(2:end-1);
            %Canal 1 de y_2
            ch1_y_2 = y_2(:,1);
            yX1_y1_2 = fft(ch1_y_2);
            yX1_bi_y1_2 = abs(yX1_y1_2/length(ch1_y_2));
            yX1_uni_y1_2 = yX1_bi_y1_2(1:length(ch1_y_2)/2+1);
            yX1_uni_y1_2(2:end-1) = 2*yX1_uni_y1_2(2:end-1);
            %Canal 2 de y_2
            ch2_y_2 = y_2(:,2);
            yX1_y2_2 = fft(ch2_y_2);
            yX1_bi_y2_2 = abs(yX1_y2_2/length(ch2_y_2));
            yX1_uni_y2_2 = yX1_bi_y2_2(1:length(ch2_y_2)/2+1);
            yX1_uni_y2_2(2:end-1) = 2*yX1_uni_y2_2(2:end-1);
            
            %Gráfica
            figure(4);
            clf;
            subplot(2, 2, 1);
            plot(axis_x_2(1:44100), yX1_uni_x1(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 1 de x.');
            subplot(2, 2, 2);
            plot(axis_x_2(1:44100), yX1_uni_x2(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 2 de x.');
            subplot(2, 2, 3);
            plot(axis_x_2(1:44100), yX1_uni_y1_2(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 1 de y_2.');
            subplot(2, 2, 4);
            plot(axis_x_2(1:44100), yX1_uni_y2_2(1:44100));
            grid on;
            xlabel('Frecuencia (Hz)');
            ylabel('Amplitud');
            title('Espectro unilateral del canal 2 de y_2.');
        end

        % Value changed function: VibratoSwitch
        function VibratoSwitchValueChanged(app, event)
            value = app.VibratoSwitch.Value;
            x=evalin("base","x");
            Fs=evalin("base","Fs");
            if strcmp(value,"On")
                % Definición de canales
                ch1_x = x(:,1);
                ch2_x = x(:,2);
                
                % Definición de frecuencia de modulación 
                Modfreq = 4; 
                % Definición de delay base
                Width = 0.005; 
                % Delay base de la entrada
                Delay=Width; 
                % Delay base por número de muestras
                DELAY=round(Delay*Fs); 
                % Ancho de modulación por número de muestras
                WIDTH=round(Width*Fs); 
                % Frecuencia de modulación por número de muestras
                MODFREQ=Modfreq/Fs; 
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_1=length(ch1_x);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch1_y=zeros(size(ch1_x));     
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_1-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[ch1_x(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch1_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                %********************* APLICACIÓN A PRIMER CANAL *************************
                %-------------------------------------------------------------------------
                % Definición del primer largo, depende del número de muestras del canal 1
                LEN_2=length(ch2_x);        
                % Largo del delay
                L=2+DELAY+WIDTH*2;    
                % Definición del tamaño de la  memoria del delay
                Delayline=zeros(L,1); 
                % Definición del tamaño de la salida
                ch2_y=zeros(size(ch2_x));    
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n=1:(LEN_2-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[ch2_x(n);Delayline(1:L-1)]; 
                   % Se utilizó la Interpolación lineal
                   ch2_y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                %-------------------------------------------------------------------------
                % Concatenación de los dos canales
                y = horzcat(ch1_y, ch2_y);
                assignin("base","ch5_x",y);
            else
                assignin("base","ch5_x",x)
            end
        end

        % Value changed function: DistortionSwitch_2
        function DistortionSwitch_2ValueChanged(app, event)
            value = app.DistortionSwitch_2.Value;
            ch2_x = evalin("base","ch2_x");
            gain=1;
            mix=5;
            if strcmp(value,'On')
                q=ch2_x*gain;
                z=sign(q).*(1-exp(-abs(q)));
                y=mix*z+(1-mix)*ch2_x;
                assignin("base","ch2_x",y);
            end
            if strcmp(value,'Off')
               assignin("base","ch2_x",ch1_x);
            end
        end

        % Value changed function: ReverbSwitch_2
        function ReverbSwitch_2ValueChanged(app, event)
            value = app.ReverbSwitch_2.Value;
            x = evalin("base","ch2_x");
            % Definición de canales
            ch1_x = x(:,1);
            ch2_x = x(:,2);
            if strcmp(value,"On")
                %-------------------------------------------------------------------------
                %*********************** GENERACIÓN DE IMPULSO ***************************
                %-------------------------------------------------------------------------
                % Definición de tamaños de memorias y efectos
                xh = zeros(1, 2500);
                xh(1) = 1;
                A = zeros(1, 44000);
                Adelay = 1000;
                ir = zeros(1, 2500);
                g = 0.7;
                % Ciclo FOR a utilizar, funciones obtenidas de (Zölzer, 2011).
                for n = 1:length(ir)
                    tmp = A(Adelay) + xh(n)*(-g);
                    A = [(tmp*g + xh(n))' A(1:length(A) - 1)];
                    ir(n) = tmp;
                end
                %-------------------------------------------------------------------------
                % Convolución entre los canales y el impulso creado
                ch1_y = conv (ch1_x,ir);
                ch2_y = conv (ch2_x,ir);
                
                % Concatenación de los dos canales
                y = horzcat(ch1_y, ch2_y);
                assignin("base","ch2_x",y);
            end
        end

        % Value changed function: FlangerSwitch
        function FlangerSwitchValueChanged(app, event)
            value = app.FlangerSwitch.Value;
            x=evalin("base","ch1_x");
            Fs=evalin("base","Fs");
            if strcmp(value,"On")
                ch1_x = x(:,1);
                ch2_x = x(:,2);
                
                % Aplicación de la función flange, guardandola en una nueva varaible
                ch1_y = flange(Fs,0.002,ch1_x,0.5);
                ch2_y = flange(Fs,0.002,ch2_x,0.5);
                
                % Concatenación de los dos canales
                y = horzcat(ch1_y, ch2_y);
                assignin("base","ch6_x",y);

            end
        end

        % Value changed function: Volumechannel1Knob
        function Volumechannel1KnobValueChanged(app, event)
            value = app.Volumechannel1Knob.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectsongButton = uibutton(app.UIFigure, 'push');
            app.SelectsongButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsongButtonPushed, true);
            app.SelectsongButton.Text = 'Select song';
            app.EcoSwitchLabel = uilabel(app.UIFigure);
            app.EcoSwitchLabel.Text = 'Eco';
            app.EcoSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EcoSwitch.ValueChangedFcn = createCallbackFcn(app, @EcoSwitchValueChanged, true);
            app.DistortionSwitchLabel = uilabel(app.UIFigure);
            app.DistortionSwitchLabel.Text = 'Distortion';
            app.DistortionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DistortionSwitch.ValueChangedFcn = createCallbackFcn(app, @DistortionSwitchValueChanged, true);
            app.ReverbSwitchLabel = uilabel(app.UIFigure);
            app.ReverbSwitchLabel.Text = 'Reverb.';
            app.ReverbSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ReverbSwitch.ValueChangedFcn = createCallbackFcn(app, @ReverbSwitchValueChanged, true);
            app.VibratoSwitchLabel = uilabel(app.UIFigure);
            app.VibratoSwitchLabel.Text = 'Vibrato';
            app.VibratoSwitch = uiswitch(app.UIFigure, 'toggle');
            app.VibratoSwitch.ValueChangedFcn = createCallbackFcn(app, @VibratoSwitchValueChanged, true);
            app.FlangerSwitchLabel = uilabel(app.UIFigure);
            app.FlangerSwitchLabel.Text = 'Flanger';
            app.FlangerSwitch = uiswitch(app.UIFigure, 'toggle');
            app.FlangerSwitch.ValueChangedFcn = createCallbackFcn(app, @FlangerSwitchValueChanged, true);
            app.EcoSwitch_2Label = uilabel(app.UIFigure);
            app.EcoSwitch_2Label.Text = {'Eco'; ''};
            app.EcoSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.EcoSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EcoSwitch_2ValueChanged, true);
            app.DistortionSwitch_2Label = uilabel(app.UIFigure);
            app.DistortionSwitch_2Label.Text = 'Distortion';
            app.DistortionSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.DistortionSwitch_2.ValueChangedFcn = createCallbackFcn(app, @DistortionSwitch_2ValueChanged, true);
            app.ReverbSwitch_2Label = uilabel(app.UIFigure);
            app.ReverbSwitch_2Label.Text = 'Reverb.';
            app.ReverbSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.ReverbSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ReverbSwitch_2ValueChanged, true);
            app.VibratoSwitch_2Label = uilabel(app.UIFigure);
            app.VibratoSwitch_2Label.Text = 'Vibrato';
            app.VibratoSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.FlangerSwitch_2Label = uilabel(app.UIFigure);
            app.FlangerSwitch_2Label.Text = 'Flanger';
            app.FlangerSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Efectoscanal1Label = uilabel(app.UIFigure);
            app.Efectoscanal1Label.Text = 'Efectos canal 1';
            app.Efectoscanal2Label = uilabel(app.UIFigure);
            app.Efectoscanal2Label.Text = {'Efectos canal 2'; ''};
            app.EfectosdeSonidoLabel = uilabel(app.UIFigure);
            app.EfectosdeSonidoLabel.Text = {'Efectos de Sonido'; ''};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '0-1052_image-green-youtube-play-button-hd-png-download.png';
            app.Button.Text = '';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1.';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step 2.';
            app.SelectFCL1EditFieldLabel = uilabel(app.UIFigure);
            app.SelectFCL1EditFieldLabel.Text = 'Select FCL 1';
            app.SelectFCL1EditField = uieditfield(app.UIFigure, 'text');
            app.SelectFCL1EditField.ValueChangedFcn = createCallbackFcn(app, @SelectFCL1EditFieldValueChanged, true);
            app.SelectFCH2EditFieldLabel = uilabel(app.UIFigure);
            app.SelectFCH2EditFieldLabel.Text = 'Select FCH 2';
            app.SelectFCH2EditField = uieditfield(app.UIFigure, 'text');
            app.SelectFCH2EditField.ValueChangedFcn = createCallbackFcn(app, @SelectFCH2EditFieldValueChanged, true);
            app.SelectFCL2EditFieldLabel = uilabel(app.UIFigure);
            app.SelectFCL2EditFieldLabel.Text = 'Select FCL 2';
            app.SelectFCL2EditField = uieditfield(app.UIFigure, 'text');
            app.SelectFCL2EditField.ValueChangedFcn = createCallbackFcn(app, @SelectFCL2EditFieldValueChanged, true);
            app.SelectFCH2EditField_2Label = uilabel(app.UIFigure);
            app.SelectFCH2EditField_2Label.Text = 'Select FCH 2';
            app.SelectFCH2EditField_2 = uieditfield(app.UIFigure, 'text');
            app.SelectFCH2EditField_2.ValueChangedFcn = createCallbackFcn(app, @SelectFCH2EditField_2ValueChanged, true);
            app.ApplyfilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Text = 'Apply filter';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step 3.';
            app.Volumechannel1KnobLabel = uilabel(app.UIFigure);
            app.Volumechannel1KnobLabel.Text = {'Volume'; 'channel 1'};
            app.Volumechannel1Knob = uiknob(app.UIFigure, 'continuous');
            app.Volumechannel1Knob.ValueChangedFcn = createCallbackFcn(app, @Volumechannel1KnobValueChanged, true);
            app.Volumechannel2KnobLabel = uilabel(app.UIFigure);
            app.Volumechannel2KnobLabel.Text = {'Volume'; 'channel 2'; ''};
            app.Volumechannel2Knob = uiknob(app.UIFigure, 'continuous');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = musica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end